classdef CalciumProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TrimButton      matlab.ui.control.Button
        AddCellButton   matlab.ui.control.Button
        right           matlab.ui.control.Button
        left            matlab.ui.control.Button
        Label           matlab.ui.control.Label
        LoadFileButton  matlab.ui.control.Button
        ImageSlider     matlab.ui.control.Slider
        ImageLabel      matlab.ui.control.Label
        PlayButton      matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myFolder    
        filePattern
        tifFiles   
        play
        roi
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes,'DataAspectRatio',[2 2 1])
            app.roi = {};
        end

        % Button pushed function: LoadFileButton
        function LoadFIle(app, event)
            try
                app.myFolder = uigetdir('C:');  % get file
                app.filePattern = fullfile(app.myFolder, '*.tif'); % gets all the tif files
                app.tifFiles = dir(app.filePattern);
                
                len = length(app.tifFiles); % takes length and adjusts the slider
                app.ImageSlider.Limits = [0 len-1];
                
                set(app.ImageSlider, 'value', 1) % sets slider
                baseFileName = app.tifFiles(1).name; % gets specific image
                fullFileName = fullfile(app.myFolder, baseFileName);
                imageArray = imread(fullFileName);
                app.Label.Text = baseFileName; % displays image on axis and displays name
                % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
                % Display image and stretch to fill axes
                I = imshow(imageArray, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                drawnow;
            catch
                warning("There was an error in selecting your files. Please try again.");
            end
        end

        % Button pushed function: left
        function leftPressed(app, event)
            x = app.ImageSlider.Value;
            y = x-1;
            set(app.ImageSlider, 'value', y)
            
            baseFileName = app.tifFiles(round(y)).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            x = app.ImageSlider.Value;
            y = x+1;
            set(app.ImageSlider, 'value', y)
            
            baseFileName = app.tifFiles(round(y)).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.play = not(app.play);
        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            x = event.Value;
            
            baseFileName = app.tifFiles(round(x)+1).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);    
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow; % Force display to update immediately.
        end

        % Button pushed function: AddCellButton
        function AddCellButtonPushed(app, event)
            app.roi = drawpolygon(app.UIAxes); %draws polygon
            y = app.ImageSlider.Value; 
            baseFileName = app.tifFiles(round(y)).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName); %gets the image since regionprops doesn't work with UIAxes
            
            mask = createMask(app.roi); %creates mask for the roi
            avgintensity = regionprops(mask, imageArray, "Area", "MeanIntensity"); %gets the mean intensity for the mask within the image
            disp(avgintensity);
        end

        % Button pushed function: TrimButton
        function TrimButtonPushed(app, event)
            outputFolder = uigetdir('C:'); % takes in a directory for output
            answer = inputdlg("number of files in output"); % takes number
            trimfiles(app.myFolder, outputFolder, str2double(answer)); % calls function
            
            app.myFolder = outputFolder
            app.filePattern = fullfile(app.myFolder, '*.tif');
            app.tifFiles = dir(app.filePattern);
            app.play = false;
            
            len = length(app.tifFiles);
            app.ImageSlider.Limits = [0 len-1];
            
            set(app.ImageSlider, 'value', 1)
            baseFileName = app.tifFiles(1).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.UIFigure);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFIle, true);
            app.LoadFileButton.Text = {'Load File'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.left = uibutton(app.UIFigure, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<-';
            app.right = uibutton(app.UIFigure, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '->';
            app.AddCellButton = uibutton(app.UIFigure, 'push');
            app.AddCellButton.ButtonPushedFcn = createCallbackFcn(app, @AddCellButtonPushed, true);
            app.AddCellButton.Text = 'Add Cell';
            app.TrimButton = uibutton(app.UIFigure, 'push');
            app.TrimButton.ButtonPushedFcn = createCallbackFcn(app, @TrimButtonPushed, true);
            app.TrimButton.Text = 'Trim';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciumProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end